classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        UITable             matlab.ui.control.Table
        Label               matlab.ui.control.Label
        Button_init         matlab.ui.control.Button
        Button_mainPlot     matlab.ui.control.Button
        Label_2             matlab.ui.control.Label
        samplingRate        matlab.ui.control.NumericEditField
        EditField_2Label    matlab.ui.control.Label
        samplingExtent      matlab.ui.control.NumericEditField
        U201810832Label     matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        signalVPP           matlab.ui.control.NumericEditField
        EditField_2Label_2  matlab.ui.control.Label
        signal              matlab.ui.control.NumericEditField
        Label_5             matlab.ui.control.Label
        EditField_3         matlab.ui.control.NumericEditField
        Label_6             matlab.ui.control.Label
        EditField_4         matlab.ui.control.NumericEditField
        Button_2            matlab.ui.control.StateButton
        Button              matlab.ui.control.Button
        DropDown            matlab.ui.control.DropDown
        Panel               matlab.ui.container.Panel
        CenterPanel         matlab.ui.container.Panel
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        UIAxes_main         matlab.ui.control.UIAxes
        Tab_2               matlab.ui.container.Tab
        UIAxes_signal2      matlab.ui.control.UIAxes
        Tab3                matlab.ui.container.Tab
        UIAxes_result       matlab.ui.control.UIAxes
        TabGroup2           matlab.ui.container.TabGroup
        Tab_3               matlab.ui.container.Tab
        UIAxes5             matlab.ui.control.UIAxes
        Tab_4               matlab.ui.container.Tab
        UIAxes4             matlab.ui.control.UIAxes
        RightPanel          matlab.ui.container.Panel
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes3             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Y % 信号纵坐标
        Fs% 采样频率
        N % 采样长度
        t %时间长度
        X % 横坐标
        
    end
    
    methods (Access = private)
        
        function mainPlot(app)
            plot(app.UIAxes_main,app.X,app.Y);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            a = [1,2,0;
                2,1,pi/2;
                4,4,0];
            app.t = 5;
            app.samplingRate.Value = 5120;
            app.Fs = app.samplingRate.Value;
            app.samplingExtent.Value = 1000;
            app.N = app.samplingExtent.Value;
            app.UITable.Data  = a;
            
            Button_mainPlotPushed(app)
        end

        % Button pushed function: Button_init
        function Button_initPushed(app, event)
            startupFcn(app);
        end

        % Button pushed function: Button_mainPlot
        function Button_mainPlotPushed(app, event)
            D = app.UITable.Data;
            m =size(D,1);
            app.X = linspace(0,app.t,round(app.Fs*app.t));
            app.Y = D(1,2)*sin(2*pi*D(1,1)*app.X+D(1,3));
            if m>1
                for i = 2:m
                    app.Y = app.Y + D(i,2)*sin(2*pi*D(i,1)*app.X+D(i,3));
                end
            end
            plot(app.UIAxes_main,app.X,app.Y);
            drawnow;
        end

        % Value changed function: samplingRate
        function samplingRateValueChanged(app, event)
            v = app.samplingRate.Value;
            app.Fs = round(v) ;
            Button_mainPlotPushed(app);
        end

        % Value changed function: samplingExtent
        function samplingExtentValueChanged(app, event)
            app.N = app.samplingExtent.Value;
            Button_mainPlotPushed(app);            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            Button_mainPlotPushed(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {811, 811, 811};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {811, 811};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {232, '1x', 373};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'频率(Hz)'; '幅值'; '初相位'};
            app.UITable.RowName = {'1'; '2'; '3'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '基本信息';
            app.Button_init = uibutton(app.LeftPanel, 'push');
            app.Button_init.ButtonPushedFcn = createCallbackFcn(app, @Button_initPushed, true);
            app.Button_init.Text = '初始化';
            app.Button_mainPlot = uibutton(app.LeftPanel, 'push');
            app.Button_mainPlot.ButtonPushedFcn = createCallbackFcn(app, @Button_mainPlotPushed, true);
            app.Button_mainPlot.Text = '绘制图像';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '采样频率';
            app.samplingRate = uieditfield(app.LeftPanel, 'numeric');
            app.samplingRate.Limits = [0 Inf];
            app.samplingRate.RoundFractionalValues = 'on';
            app.samplingRate.ValueChangedFcn = createCallbackFcn(app, @samplingRateValueChanged, true);
            app.samplingRate.Value = 500;
            app.EditField_2Label = uilabel(app.LeftPanel);
            app.EditField_2Label.Text = '采样长度';
            app.samplingExtent = uieditfield(app.LeftPanel, 'numeric');
            app.samplingExtent.ValueChangedFcn = createCallbackFcn(app, @samplingExtentValueChanged, true);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = 'Panel';
            app.U201810832Label = uilabel(app.Panel);
            app.U201810832Label.Text = '李聪 U201810832';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '峰峰值';
            app.signalVPP = uieditfield(app.Panel, 'numeric');
            app.EditField_2Label_2 = uilabel(app.Panel);
            app.EditField_2Label_2.Text = '峰值';
            app.signal = uieditfield(app.Panel, 'numeric');
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '有效值';
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '平均值';
            app.EditField_4 = uieditfield(app.Panel, 'numeric');
            app.Button_2 = uibutton(app.Panel, 'state');
            app.Button_2.Text = '显示特征值';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '输入信号';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'采集卡', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown.Value = '采集卡';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.Text = '互相关分析';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '主信号';
            app.UIAxes_main = uiaxes(app.Tab);
            title(app.UIAxes_main, '信号输出')
            xlabel(app.UIAxes_main, 'X')
            ylabel(app.UIAxes_main, 'Y')
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '输入信号';
            app.UIAxes_signal2 = uiaxes(app.Tab_2);
            title(app.UIAxes_signal2, '输入信号')
            xlabel(app.UIAxes_signal2, 'X')
            ylabel(app.UIAxes_signal2, 'Y')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes_result = uiaxes(app.CenterPanel);
            title(app.UIAxes_result, '分析结果')
            xlabel(app.UIAxes_result, 'X')
            ylabel(app.UIAxes_result, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, '功率谱')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, '幅值谱')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '实频谱';
            app.UIAxes5 = uiaxes(app.Tab_3);
            title(app.UIAxes5, '')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '虚频谱';
            app.UIAxes4 = uiaxes(app.Tab_4);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end